classdef mainfrm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SignalDemoUIFigure            matlab.ui.Figure
        AnimationSpeedEdit            matlab.ui.control.NumericEditField
        sEditFieldLabel               matlab.ui.control.Label
        HiddenTabGroup                matlab.ui.container.TabGroup
        DefaultTab                    matlab.ui.container.Tab
        SquareWaveSignalSetTab        matlab.ui.container.Tab
        RectangularCycleEdit          matlab.ui.control.NumericEditField
        Label_16                      matlab.ui.control.Label
        RectangularSamplingFrequencyEdit  matlab.ui.control.EditField
        Label_9                       matlab.ui.control.Label
        RectangularWaveDutyCycleEdit  matlab.ui.control.NumericEditField
        Label_7                       matlab.ui.control.Label
        RectangularWavePeriodEdit     matlab.ui.control.NumericEditField
        Label_6                       matlab.ui.control.Label
        RectangularAmplitudeEdit      matlab.ui.control.NumericEditField
        Label_5                       matlab.ui.control.Label
        TriangularWaveSetTab          matlab.ui.container.Tab
        TriangularWaveSignalAmplitudeEdit  matlab.ui.control.EditField
        Label_18                      matlab.ui.control.Label
        TriangularWaveSignalFrequencyEdit  matlab.ui.control.EditField
        Label_17                      matlab.ui.control.Label
        AddNoiseButton                matlab.ui.control.Button
        ClearSignalsButton            matlab.ui.control.Button
        NoiseReductionButton          matlab.ui.control.Button
        ButtonGroup                   matlab.ui.container.ButtonGroup
        TriangularWaveSignalButton    matlab.ui.control.RadioButton
        SquareWaveSignalButton        matlab.ui.control.RadioButton
        SineWaveSignalButton          matlab.ui.control.RadioButton
        EnableAnimationCheckBox       matlab.ui.control.CheckBox
        GenerateSignalsButton         matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        SineWaveSignalSetTab          matlab.ui.container.Tab
        SineAmplitudeEdit             matlab.ui.control.EditField
        Label_4                       matlab.ui.control.Label
        SineSignalFrequencyEdit       matlab.ui.control.EditField
        Label_3                       matlab.ui.control.Label
        SineSamplingFrequencyEdit     matlab.ui.control.EditField
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        NoiseSetTab                   matlab.ui.container.Tab
        NoiseFrequencyEdit            matlab.ui.control.NumericEditField
        Label_13                      matlab.ui.control.Label
        RandomSeedEdit                matlab.ui.control.NumericEditField
        Label_12                      matlab.ui.control.Label
        NoiseIntensityEdit            matlab.ui.control.NumericEditField
        Label_11                      matlab.ui.control.Label
        Tab_2                         matlab.ui.container.Tab
        FilterOrderEdit               matlab.ui.control.NumericEditField
        Label_15                      matlab.ui.control.Label
        CutoffFrequencyEdit           matlab.ui.control.NumericEditField
        Label_14                      matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateSignalsButton
        function GenerateSignalsButtonPushed(app, event)
            global stopFlag;
            selectedButton = app.ButtonGroup.SelectedObject;
            buttonTag = selectedButton.Tag;
            animationFlag = app.EnableAnimationCheckBox.Value;
            stopFlag = false;
            % 根据单选框的
            switch buttonTag
                case 'SineWaveSignal'
                    if app.EnableAnimationCheckBox.Value
                        % 从文本框中获取采样频率
                        fs = str2double(app.SineSamplingFrequencyEdit.Value);
                        t = 0:1/fs:1-1/fs; % 时间序列
                        f = str2double(app.SineSignalFrequencyEdit.Value); % 正弦信号频率
                        A = str2double(app.SineAmplitudeEdit.Value); % 正弦信号幅度
                        x = A*sin(2*pi*f*t); % 生成正弦信号
                        
                        % 可视化展示
                        hline = animatedline('Parent', app.UIAxes, 'Color', 'b');
                        xlabel(app.UIAxes,'Time (s)');
                        ylabel(app.UIAxes,'Amplitude');
                        title(app.UIAxes,'Original Signal');
                        
                        % 动态绘制正弦信号
                        for i = 1:length(t)
                            % 检查标志变量是否为真
                            if stopFlag
                                break;
                            end
                            addpoints(hline, t(i), x(i));
                            pause(str2double(app.AnimationSpeedEdit.Value));
                            %drawnow limitrate;
                            drawnow;
                        end
                    else
                        % 生成正弦信号
                        % 从文本框中获取采样频率
                        fs = str2double(app.SineSamplingFrequencyEdit.Value);
                        t = 0:1/fs:1-1/fs; % 时间序列
                        f = str2double(app.SineSignalFrequencyEdit.Value); % 正弦信号频率
                        A = str2double(app.SineAmplitudeEdit.Value); % 正弦信号幅度
                        x = A*sin(2*pi*f*t); % 生成正弦信号
                        
                        % 可视化展示
                        plot(app.UIAxes,t,x);
                        xlabel(app.UIAxes,'Time (s)');
                        ylabel(app.UIAxes,'Amplitude');
                        title(app.UIAxes,'Original Signal');
                        
                        % 添加噪声
                        noise = 0.1*randn(size(x)); % 生成高斯白噪声
                        y = x + noise; % 添加噪声
                        
                        % 可视化展示
                        %subplot(2,1,2);
                        %plot(app.UIAxes,t,y);
                        %xlabel(app.UIAxes,'Time (s)');
                        %ylabel(app.UIAxes,'Amplitude');
                        %title(app.UIAxes,'Purre Signal');
                    end
                case 'SquareWaveSignal'
                    if app.EnableAnimationCheckBox.Value
                        % 采样频率
                        fs = str2double(app.RectangularSamplingFrequencyEdit.Value);
                        % 幅值
                        A = app.RectangularAmplitudeEdit.Value;
                        % 周期
                        T = app.RectangularWavePeriodEdit.Value;
                        % 占空比
                        D = app.RectangularWaveDutyCycleEdit.Value;
                        
                        % 方波参数
                        numCycles = app.RectangularCycleEdit.Value; % 方波的周期数
                        
                        % 计算每个周期的采样点数
                        samplesPerCycle = round(fs * T);
                        
                        % 生成方波
                        t = linspace(0, numCycles*T, numCycles*samplesPerCycle); % 生成多个周期的时间序列
                        waveform = zeros(size(t)); % 初始化方波信号
                        
                        % 生成方波的上升和下降边缘
                        for i = 1:numCycles
                            startIndex = (i-1)*samplesPerCycle + 1;
                            endIndex = i*samplesPerCycle;
                            
                            % 上升边缘
                            waveform(startIndex:startIndex+round(samplesPerCycle*D)-1) = A;
                            % 下降边缘
                            waveform(startIndex+round(samplesPerCycle*D):endIndex) = -A;
                        end
                        
                        % 可视化展示
                        hline = animatedline('Parent', app.UIAxes, 'Color', 'b');
                        xlabel(app.UIAxes,'Time');
                        ylabel(app.UIAxes,'Amplitude');
                        title(app.UIAxes,'Rectangular Wave');
                        
                        % 动态绘制方波信号
                        for i = 1:length(t)
                            % 检查标志变量是否为真
                            if stopFlag
                                break;
                            end
                            addpoints(hline, t(i), waveform(i));
                            drawnow limitrate;
                            pause(str2double(app.AnimationSpeedEdit.Value));
                        end
                    else
                        % 生成方波信号
    
                        % 采样频率
                        fs = str2double(app.RectangularSamplingFrequencyEdit.Value);
                        % 幅值
                        A = app.RectangularAmplitudeEdit.Value;
                        % 周期
                        T = app.RectangularWavePeriodEdit.Value;
                        % 占空比
                        D = app.RectangularWaveDutyCycleEdit.Value;
                        
                        % 方波参数
                        numCycles = app.RectangularCycleEdit.Value; % 方波的周期数
                        
                        % 计算每个周期的采样点数
                        samplesPerCycle = round(fs * T);
                        
                        % 生成方波
                        t = linspace(0, numCycles*T, numCycles*samplesPerCycle); % 生成多个周期的时间序列
                        waveform = zeros(size(t)); % 初始化方波信号
                        
                        % 生成方波的上升和下降边缘
                        for i = 1:numCycles
                            startIndex = (i-1)*samplesPerCycle + 1;
                            endIndex = i*samplesPerCycle;
                            
                            % 上升边缘
                            waveform(startIndex:startIndex+round(samplesPerCycle*D)-1) = A;
                            % 下降边缘
                            waveform(startIndex+round(samplesPerCycle*D):endIndex) = -A;
                        end
                        
                        % 绘制方波
                        plot(app.UIAxes,t, waveform);
                        xlabel(app.UIAxes,'Time');
                        ylabel(app.UIAxes,'Amplitude');
                        title(app.UIAxes,'Rectangular Wave');
                    end
                case 'TriangularWaveSignal'
                    if app.EnableAnimationCheckBox.Value
                        % 生成三角波信号
                        t = linspace(0, 1, 1000); % 时间序列
                        frequency = str2double(app.TriangularWaveSignalFrequencyEdit.Value); % 三角波的频率
                        amplitude = str2double(app.TriangularWaveSignalAmplitudeEdit.Value); % 三角波的幅值
                        triangleWave = amplitude * sawtooth(2*pi*frequency*t, 0.5); % 生成三角波信号
                        
                        % 创建动画对象
                        h = animatedline(app.UIAxes, 'Color', 'b');
                        
                        % 设置坐标轴范围
                        axis(app.UIAxes, [0 1 -amplitude amplitude]);
                        
                        % 循环绘制三角波信号
                        for i = 1:length(t)
                            % 检查标志变量是否为真
                            if stopFlag
                                break;
                            end
                            % 添加新的数据点到动画对象中
                            addpoints(h, t(i), triangleWave(i));
                            pause(str2double(app.AnimationSpeedEdit.Value));
                            % 刷新图形界面
                            drawnow;
                        end
                        
                        % 设置坐标轴标签和标题
                        xlabel(app.UIAxes, 'Time (s)');
                        ylabel(app.UIAxes, 'Amplitude');
                        title(app.UIAxes, 'Triangle Wave');
                    else
                        % 生成三角波信号
                        t = linspace(0, 1, 1000); % 时间序列
                        frequency = str2double(app.TriangularWaveSignalFrequencyEdit.Value); % 三角波的频率
                        amplitude = str2double( app.TriangularWaveSignalAmplitudeEdit.Value); % 三角波的幅值
                        triangleWave = amplitude * sawtooth(2*pi*frequency*t, 0.5); % 生成三角波信号
                        
                        % 绘制三角波信号
                        plot(app.UIAxes, t, triangleWave);
                        xlabel(app.UIAxes, 'Time (s)');
                        ylabel(app.UIAxes, 'Amplitude');
                        title(app.UIAxes, 'Triangle Wave');
                    end
                otherwise
                    % 啥也不做
            end

        end

        % Button pushed function: ClearSignalsButton
        function ClearSignalsButtonPushed(app, event)
            global stopFlag;
            stopFlag = true;
            cla(app.UIAxes);
            title(app.UIAxes,'No Signal');
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            buttonTag = selectedButton.Tag;
            f = app.TabGroup.SelectedTab.Title == "信号设置";
            % 根据单选框的Tag更新选项卡组内的选项可见性
            switch buttonTag
                case 'SineWaveSignal'
                    app.SineWaveSignalSetTab.Parent = app.TabGroup;
                    app.SquareWaveSignalSetTab.Parent = app.HiddenTabGroup;
                    app.TriangularWaveSetTab.Parent = app.HiddenTabGroup;
                    uistack(app.SineWaveSignalSetTab,"top");
                    if f
                        app.TabGroup.SelectedTab = app.SineWaveSignalSetTab;
                    end
                case 'SquareWaveSignal'             
                    app.SineWaveSignalSetTab.Parent = app.HiddenTabGroup;
                    app.SquareWaveSignalSetTab.Parent = app.TabGroup;
                    app.TriangularWaveSetTab.Parent = app.HiddenTabGroup;
                    uistack(app.SquareWaveSignalSetTab,"top");
                    if f
                        app.TabGroup.SelectedTab = app.SquareWaveSignalSetTab;
                    end
                case 'TriangularWaveSignal'
                    app.SineWaveSignalSetTab.Parent = app.HiddenTabGroup;
                    app.SquareWaveSignalSetTab.Parent = app.HiddenTabGroup;
                    app.TriangularWaveSetTab.Parent = app.TabGroup;
                    uistack(app.TriangularWaveSetTab,"top");
                    if f
                        app.TabGroup.SelectedTab = app.TriangularWaveSetTab;
                    end
                otherwise
                    % 啥也不做
            end
        end

        % Button pushed function: AddNoiseButton
        function AddNoiseButtonPushed(app, event)
            global stopFlag;
            stopFlag = false;
            selectedButton = app.ButtonGroup.SelectedObject;
            buttonTag = selectedButton.Tag;
            f = app.TabGroup.SelectedTab.Title == "信号设置";
            % 根据单选框的Tag更新选项卡组内的选项可见性
            switch buttonTag
                case 'SineWaveSignal'
                    if app.EnableAnimationCheckBox.Value
                        noiseFrequency = app.NoiseFrequencyEdit.Value;
                        noiseIntensity = app.NoiseIntensityEdit.Value;
                        randomSeed = app.RandomSeedEdit.Value;
                        
                        % 获取参数
                        fs = str2double(app.SineSamplingFrequencyEdit.Value);
                        f = str2double(app.SineSignalFrequencyEdit.Value);
                        a = str2double(app.SineAmplitudeEdit.Value);
                        
                        % 生成时间序列
                        t = 0:1/fs:1; % 时间序列，假设采样时长为 1 秒
                        
                        % 生成正弦信号
                        signal = a * sin(2*pi*f*t);
                        
                        % 设置随机数种子
                        rng(randomSeed);
                        
                        % 生成噪声
                        noise = noiseIntensity * randn(size(t)); % 使用高斯分布随机数生成噪声
                        
                        % 添加噪声到信号
                        noisySignal = signal + noise;
                        
                        % 绘制结果
                        lineHandle = plot(app.UIAxes, t(1), noisySignal(1)); % 绘制初始图像
                        title(app.UIAxes, 'Sine Signal with noise');
                        xlabel(app.UIAxes, 'Time (s)');
                        ylabel(app.UIAxes, 'Amplitude');
                        legend(app.UIAxes, 'Signal with noise');
                        
                        % 动画绘制
                        for i = 2:length(t)
                            % 检查标志变量是否为真
                            if stopFlag
                                break;
                            end
                            set(lineHandle, 'XData', t(1:i), 'YData', noisySignal(1:i)); % 更新图像
                            pause(str2double(app.AnimationSpeedEdit.Value));
                            drawnow; % 刷新图像
                        end
                    else
                        noiseFrequency = app.NoiseFrequencyEdit.Value;
                        noiseIntensity = app.NoiseIntensityEdit.Value;
                        randomSeed = app.RandomSeedEdit.Value;
            
                        % 获取参数
                        fs = str2double(app.SineSamplingFrequencyEdit.Value);
                        f = str2double(app.SineSignalFrequencyEdit.Value);
                        a = str2double(app.SineAmplitudeEdit.Value);
            
                        % 生成时间序列
                        t = 0:1/fs:1; % 时间序列，假设采样时长为 1 秒
                        
                        % 生成正弦信号
                        signal = a * sin(2*pi*f*t);
                        
                        % 设置随机数种子
                        rng(randomSeed);
                        
                        % 生成噪声
                        noise = noiseIntensity * randn(size(t)); % 使用高斯分布随机数生成噪声
                        
                        % 添加噪声到信号
                        noisySignal = signal + noise;
                        
                        % 绘制结果
                        plot(app.UIAxes, t, noisySignal);
                        title(app.UIAxes, 'Sine Signal with noise');
                        xlabel(app.UIAxes, 'Time (s)');
                        ylabel(app.UIAxes, 'Amplitude');
                        legend(app.UIAxes, 'Signal with noise');
                    end
                case 'SquareWaveSignal'
                    % 采样频率
                    fs = str2double(app.RectangularSamplingFrequencyEdit.Value);
                    % 幅值
                    A = app.RectangularAmplitudeEdit.Value;
                    % 周期
                    T = app.RectangularWavePeriodEdit.Value;
                    % 占空比
                    D = app.RectangularWaveDutyCycleEdit.Value;
                    
                    % 噪声参数
                    noiseFrequency = app.NoiseFrequencyEdit.Value;
                    noiseIntensity = app.NoiseIntensityEdit.Value;
                    randomSeed = app.RandomSeedEdit.Value;
                    
                    % 方波参数
                    numCycles = 5; % 方波的周期数
                    
                    % 计算每个周期的采样点数
                    samplesPerCycle = round(fs * T);
                    
                    % 生成方波
                    t = linspace(0, numCycles*T, numCycles*samplesPerCycle); % 生成多个周期的时间序列
                    waveform = zeros(size(t)); % 初始化方波信号
                    
                    % 生成方波的上升和下降边缘
                    for i = 1:numCycles
                        startIndex = (i-1)*samplesPerCycle + 1;
                        endIndex = i*samplesPerCycle;
                        
                        % 上升边缘
                        waveform(startIndex:startIndex+round(samplesPerCycle*D)-1) = A;
                        % 下降边缘
                        waveform(startIndex+round(samplesPerCycle*D):endIndex) = -A;
                    end
                    
                    % 添加噪声
                    rng(randomSeed); % 设置随机种子
                    noise = noiseIntensity * randn(size(t)); % 生成随机噪声信号
                    smoothedNoise = smoothdata(noise, 'gaussian', noiseFrequency);
                    noisyWaveform = waveform + smoothedNoise; % 添加噪声


                    % 绘制带噪声的方波
                    plot(app.UIAxes,t, noisyWaveform);
                    xlabel(app.UIAxes,'Time');
                    ylabel(app.UIAxes,'Amplitude');
                    title(app.UIAxes,'Square signal with noise');
                    legend(app.UIAxes, 'Signal with noise');
                case 'TriangularWaveSignal'
                    noiseFrequency = app.NoiseFrequencyEdit.Value;
                    noiseIntensity = app.NoiseIntensityEdit.Value;
                    randomSeed = app.RandomSeedEdit.Value;
                    % 生成三角波信号
                    t = linspace(0, 1, 1000); % 时间序列
                    frequency = str2double(app.TriangularWaveSignalFrequencyEdit.Value); % 三角波的频率
                    amplitude = str2double(app.TriangularWaveSignalAmplitudeEdit.Value); % 三角波的幅值
                    triangleWave = amplitude * sawtooth(2*pi*frequency*t, 0.5); % 生成三角波信号
                    
                    % 设置随机数种子
                    rng(randomSeed);
                    
                    % 生成噪声
                    noise = noiseIntensity * randn(size(t)); % 使用高斯分布随机数生成噪声
                    
                    % 添加噪声到信号
                    noisySignal = triangleWave + noise;

                    % 绘制三角波信号
                    plot(app.UIAxes, t, noisySignal);
                    xlabel(app.UIAxes, 'Time (s)');
                    ylabel(app.UIAxes, 'Amplitude');
                    title(app.UIAxes, 'Triangle Wave with noise');
                    legend(app.UIAxes, 'Signal with noise');
                otherwise
                    % 啥也不做
            end
        end

        % Button pushed function: NoiseReductionButton
        function NoiseReductionButtonPushed(app, event)
            global stopFlag;
            stopFlag = false;
            selectedButton = app.ButtonGroup.SelectedObject;
            buttonTag = selectedButton.Tag;
            f = app.TabGroup.SelectedTab.Title == "信号设置";
            % 根据单选框的Tag更新选项卡组内的选项可见性
            switch buttonTag
                case 'SineWaveSignal'
                    if app.EnableAnimationCheckBox.Value
                        noiseFrequency = app.NoiseFrequencyEdit.Value;
                        noiseIntensity = app.NoiseIntensityEdit.Value;
                        randomSeed = app.RandomSeedEdit.Value;
            
                        % 获取参数
                        fs = str2double(app.SineSamplingFrequencyEdit.Value);
                        f = str2double(app.SineSignalFrequencyEdit.Value);
                        a = str2double(app.SineAmplitudeEdit.Value);
                        cutoffFrequency = app.CutoffFrequencyEdit.Value; % 截止频率
                        
                        % 生成时间序列
                        t = 0:1/fs:1; % 时间序列，假设采样时长为 1 秒
                        
                        % 生成正弦信号
                        signal = a * sin(2*pi*f*t);
                        
                        % 设置随机数种子
                        rng(randomSeed);
                        
                        % 生成噪声
                        noise = noiseIntensity * randn(size(t)); % 使用高斯分布随机数生成噪声
                        
                        % 添加噪声到信号
                        noisySignal = signal + noise;
                        
                        % 设计低通滤波器
                        filterOrder = app.FilterOrderEdit.Value; % 滤波器阶数
                        normalizedCutoffFrequency = cutoffFrequency / (fs/2); % 归一化截止频率
                        [b, a] = butter(filterOrder, normalizedCutoffFrequency, 'low'); % 设计低通滤波器
                        
                        % 应用滤波器
                        filteredSignal = filter(b, a, noisySignal);
                        
                        hline1 = animatedline('Parent', app.UIAxes, 'Color', 'b');
                        hline2 = animatedline('Parent', app.UIAxes, 'Color', 'r');

                        % 动态绘制正弦信号
                        for i = 1:length(t)
                            % 检查标志变量是否为真
                            if stopFlag
                                break;
                            end
                            addpoints(hline1, t(i), noisySignal(i));
                            addpoints(hline2, t(i), filteredSignal(i));
                            pause(str2double(app.AnimationSpeedEdit.Value));
                            %drawnow limitrate;
                            drawnow;
                        end

                        % 绘制结果
                        plot(app.UIAxes, t, noisySignal, 'b', t, filteredSignal, 'r');
                        title(app.UIAxes, 'Sine Signal with noise and Filtered signal');
                        xlabel(app.UIAxes, 'Time (s)');
                        ylabel(app.UIAxes, 'Amplitude');
                        legend(app.UIAxes, 'Signal with noise', 'Filtered signal');

                    else 
                        noiseFrequency = app.NoiseFrequencyEdit.Value;
                        noiseIntensity = app.NoiseIntensityEdit.Value;
                        randomSeed = app.RandomSeedEdit.Value;
            
                        % 获取参数
                        fs = str2double(app.SineSamplingFrequencyEdit.Value);
                        f = str2double(app.SineSignalFrequencyEdit.Value);
                        a = str2double(app.SineAmplitudeEdit.Value);
                        cutoffFrequency = app.CutoffFrequencyEdit.Value; % 截止频率
                        
                        % 生成时间序列
                        t = 0:1/fs:1; % 时间序列，假设采样时长为 1 秒
                        
                        % 生成正弦信号
                        signal = a * sin(2*pi*f*t);
                        
                        % 设置随机数种子
                        rng(randomSeed);
                        
                        % 生成噪声
                        noise = noiseIntensity * randn(size(t)); % 使用高斯分布随机数生成噪声
                        
                        % 添加噪声到信号
                        noisySignal = signal + noise;
                        
                        % 设计低通滤波器
                        filterOrder = app.FilterOrderEdit.Value; % 滤波器阶数
                        normalizedCutoffFrequency = cutoffFrequency / (fs/2); % 归一化截止频率
                        [b, a] = butter(filterOrder, normalizedCutoffFrequency, 'low'); % 设计低通滤波器
                        
                        % 应用滤波器
                        filteredSignal = filter(b, a, noisySignal);
                        
                        % 绘制结果
                        plot(app.UIAxes, t, noisySignal, 'b', t, filteredSignal, 'r');
                        title(app.UIAxes, 'Sine Signal with noise and Filtered signal');
                        xlabel(app.UIAxes, 'Time (s)');
                        ylabel(app.UIAxes, 'Amplitude');
                        legend(app.UIAxes, 'Signal with noise', 'Filtered signal');
                    end
                case 'SquareWaveSignal'
                    % 采样频率
                    fs = str2double(app.RectangularSamplingFrequencyEdit.Value);
                    % 幅值
                    A = app.RectangularAmplitudeEdit.Value;
                    % 周期
                    T = app.RectangularWavePeriodEdit.Value;
                    % 占空比
                    D = app.RectangularWaveDutyCycleEdit.Value;
                    
                    % 噪声参数
                    noiseFrequency = app.NoiseFrequencyEdit.Value;
                    noiseIntensity = app.NoiseIntensityEdit.Value;
                    randomSeed = app.RandomSeedEdit.Value;
                    
                    % 方波参数
                    numCycles = 5; % 方波的周期数
                    
                    % 计算每个周期的采样点数
                    samplesPerCycle = round(fs * T);
                    
                    % 生成方波
                    t = linspace(0, numCycles*T, numCycles*samplesPerCycle); % 生成多个周期的时间序列
                    waveform = zeros(size(t)); % 初始化方波信号
                    
                    % 生成方波的上升和下降边缘
                    for i = 1:numCycles
                        startIndex = (i-1)*samplesPerCycle + 1;
                        endIndex = i*samplesPerCycle;
                        
                        % 上升边缘
                        waveform(startIndex:startIndex+round(samplesPerCycle*D)-1) = A;
                        % 下降边缘
                        waveform(startIndex+round(samplesPerCycle*D):endIndex) = -A;
                    end
                    
                    % 添加噪声
                    rng(randomSeed); % 设置随机种子
                    noise = noiseIntensity * randn(size(t)); % 生成随机噪声信号
                    smoothedNoise = smoothdata(noise, 'gaussian', noiseFrequency);
                    noisyWave = waveform + smoothedNoise; % 添加噪声

                    % 设计低通滤波器
                    filterSize = app.FilterOrderEdit.Value; % 平滑滤波器的大小
                    smoothedWave = smoothdata(noisyWave, 'gaussian', filterSize);
                    
                    % 绘制结果
                    plot(app.UIAxes, t, noisyWave, 'b', t, smoothedWave, 'r');
                    title(app.UIAxes, 'Square Signal with noise and Filtered signal');
                    xlabel(app.UIAxes, 'Time (s)');
                    ylabel(app.UIAxes, 'Amplitude');
                    legend(app.UIAxes, 'Square with noise', 'Filtered signal');
                case 'TriangularWaveSignal'
                    % 生成带噪声的三角波信号
                    noiseFrequency = app.NoiseFrequencyEdit.Value;
                    noiseIntensity = app.NoiseIntensityEdit.Value;
                    randomSeed = app.RandomSeedEdit.Value;
                    % 生成三角波信号
                    t = linspace(0, 1, 1000); % 时间序列
                    frequency = str2double(app.TriangularWaveSignalFrequencyEdit.Value); % 三角波的频率
                    amplitude = str2double(app.TriangularWaveSignalAmplitudeEdit.Value); % 三角波的幅值
                    triangleWave = amplitude * sawtooth(2*pi*frequency*t, 0.5); % 生成三角波信号
                    % 设置随机数种子
                    rng(randomSeed);
                    % 生成噪声
                    noise = noiseIntensity * randn(size(t)); % 使用高斯分布随机数生成噪声
                    % 添加噪声到信号
                    noisySignal = triangleWave + noise;
                    
                    % 对带噪声的三角波信号进行滤波
                    fc = app.CutoffFrequencyEdit.Value; % 截止频率
                    fs = 1000; % 采样率
                    [b, a] = butter(4, fc/(fs/2)); % 生成4阶Butterworth滤波器系数
                    filteredSignal = filtfilt(b, a, noisySignal); % 使用filtfilt函数进行零相移滤波
                    
                    % 绘制三角波信号和滤波后的信号
                    plot(app.UIAxes, t, noisySignal, t, filteredSignal);
                    xlabel(app.UIAxes, 'Time (s)');
                    ylabel(app.UIAxes, 'Amplitude');
                    title(app.UIAxes, 'Triangle Wave with noise and filtered signal');
                    legend(app.UIAxes, 'Signal with noise', 'Filtered signal');
                 otherwise
                    % 啥也不做
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SignalDemoUIFigure = uifigure('Visible', 'off');
            app.SignalDemoUIFigure.Name = 'Signal Demo';
            app.UIAxes = uiaxes(app.SignalDemoUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.SignalDemoUIFigure);
            app.SineWaveSignalSetTab = uitab(app.TabGroup);
            app.SineWaveSignalSetTab.Title = '信号设置';
            app.SineWaveSignalSetTab.Tag = 'SineWaveSignalSetTab';
            app.Label = uilabel(app.SineWaveSignalSetTab);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.SineWaveSignalSetTab);
            app.Label_2.Text = '采样频率：';
            app.SineSamplingFrequencyEdit = uieditfield(app.SineWaveSignalSetTab, 'text');
            app.SineSamplingFrequencyEdit.Value = '1000';
            app.Label_3 = uilabel(app.SineWaveSignalSetTab);
            app.Label_3.Text = '信号频率：';
            app.SineSignalFrequencyEdit = uieditfield(app.SineWaveSignalSetTab, 'text');
            app.SineSignalFrequencyEdit.Value = '10';
            app.Label_4 = uilabel(app.SineWaveSignalSetTab);
            app.Label_4.Text = '正弦信号幅度(幅值)：';
            app.SineAmplitudeEdit = uieditfield(app.SineWaveSignalSetTab, 'text');
            app.SineAmplitudeEdit.Value = '1';
            app.NoiseSetTab = uitab(app.TabGroup);
            app.NoiseSetTab.Title = '噪声设置';
            app.Label_11 = uilabel(app.NoiseSetTab);
            app.Label_11.Text = '噪声强度：';
            app.NoiseIntensityEdit = uieditfield(app.NoiseSetTab, 'numeric');
            app.NoiseIntensityEdit.Value = 0.04;
            app.Label_12 = uilabel(app.NoiseSetTab);
            app.Label_12.Text = '随机化参数：';
            app.RandomSeedEdit = uieditfield(app.NoiseSetTab, 'numeric');
            app.RandomSeedEdit.Value = 3;
            app.Label_13 = uilabel(app.NoiseSetTab);
            app.Label_13.Text = '噪声平滑化：';
            app.NoiseFrequencyEdit = uieditfield(app.NoiseSetTab, 'numeric');
            app.NoiseFrequencyEdit.Value = 10;
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '滤波器设置';
            app.Label_14 = uilabel(app.Tab_2);
            app.Label_14.Text = '截止频率：';
            app.CutoffFrequencyEdit = uieditfield(app.Tab_2, 'numeric');
            app.CutoffFrequencyEdit.Value = 100;
            app.Label_15 = uilabel(app.Tab_2);
            app.Label_15.Text = '滤波器阶数';
            app.FilterOrderEdit = uieditfield(app.Tab_2, 'numeric');
            app.FilterOrderEdit.Value = 4;
            app.GenerateSignalsButton = uibutton(app.SignalDemoUIFigure, 'push');
            app.GenerateSignalsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSignalsButtonPushed, true);
            app.GenerateSignalsButton.Text = 'GenerateSignals';
            app.EnableAnimationCheckBox = uicheckbox(app.SignalDemoUIFigure);
            app.EnableAnimationCheckBox.Text = 'EnableAnimation';
            app.ButtonGroup = uibuttongroup(app.SignalDemoUIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '信号类型';
            app.SineWaveSignalButton = uiradiobutton(app.ButtonGroup);
            app.SineWaveSignalButton.Tag = 'SineWaveSignal';
            app.SineWaveSignalButton.Text = 'SineWaveSignal';
            app.SineWaveSignalButton.Value = true;
            app.SquareWaveSignalButton = uiradiobutton(app.ButtonGroup);
            app.SquareWaveSignalButton.Tag = 'SquareWaveSignal';
            app.SquareWaveSignalButton.Text = 'SquareWaveSignal';
            app.TriangularWaveSignalButton = uiradiobutton(app.ButtonGroup);
            app.TriangularWaveSignalButton.Tag = 'TriangularWaveSignal';
            app.TriangularWaveSignalButton.Text = 'TriangularWaveSignal';
            app.NoiseReductionButton = uibutton(app.SignalDemoUIFigure, 'push');
            app.NoiseReductionButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseReductionButtonPushed, true);
            app.NoiseReductionButton.Text = 'NoiseReduction';
            app.ClearSignalsButton = uibutton(app.SignalDemoUIFigure, 'push');
            app.ClearSignalsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearSignalsButtonPushed, true);
            app.ClearSignalsButton.Text = 'ClearSignals';
            app.AddNoiseButton = uibutton(app.SignalDemoUIFigure, 'push');
            app.AddNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AddNoiseButtonPushed, true);
            app.AddNoiseButton.Text = 'AddNoise';
            app.HiddenTabGroup = uitabgroup(app.SignalDemoUIFigure);
            app.HiddenTabGroup.Visible = 'off';
            app.DefaultTab = uitab(app.HiddenTabGroup);
            app.DefaultTab.Title = 'HiddenTab';
            app.SquareWaveSignalSetTab = uitab(app.HiddenTabGroup);
            app.SquareWaveSignalSetTab.Title = '信号设置';
            app.SquareWaveSignalSetTab.Tag = 'SquareWaveSignalSetTab';
            app.Label_5 = uilabel(app.SquareWaveSignalSetTab);
            app.Label_5.Text = '幅值（cm）：';
            app.RectangularAmplitudeEdit = uieditfield(app.SquareWaveSignalSetTab, 'numeric');
            app.RectangularAmplitudeEdit.Value = 10;
            app.Label_6 = uilabel(app.SquareWaveSignalSetTab);
            app.Label_6.Text = '周期（s）：';
            app.RectangularWavePeriodEdit = uieditfield(app.SquareWaveSignalSetTab, 'numeric');
            app.RectangularWavePeriodEdit.Value = 2;
            app.Label_7 = uilabel(app.SquareWaveSignalSetTab);
            app.Label_7.Text = '占空比：';
            app.RectangularWaveDutyCycleEdit = uieditfield(app.SquareWaveSignalSetTab, 'numeric');
            app.RectangularWaveDutyCycleEdit.Value = 0.3;
            app.Label_9 = uilabel(app.SquareWaveSignalSetTab);
            app.Label_9.Text = '采样频率：';
            app.RectangularSamplingFrequencyEdit = uieditfield(app.SquareWaveSignalSetTab, 'text');
            app.RectangularSamplingFrequencyEdit.Value = '1000';
            app.Label_16 = uilabel(app.SquareWaveSignalSetTab);
            app.Label_16.Text = '方波周期：';
            app.RectangularCycleEdit = uieditfield(app.SquareWaveSignalSetTab, 'numeric');
            app.RectangularCycleEdit.Value = 7;
            app.TriangularWaveSetTab = uitab(app.HiddenTabGroup);
            app.TriangularWaveSetTab.Title = '信号设置';
            app.TriangularWaveSetTab.Tag = 'PulseSignalSetTab';
            app.Label_17 = uilabel(app.TriangularWaveSetTab);
            app.Label_17.Text = '信号频率：';
            app.TriangularWaveSignalFrequencyEdit = uieditfield(app.TriangularWaveSetTab, 'text');
            app.TriangularWaveSignalFrequencyEdit.Value = '10';
            app.Label_18 = uilabel(app.TriangularWaveSetTab);
            app.Label_18.Text = '三角波信号幅度(幅值)：';
            app.TriangularWaveSignalAmplitudeEdit = uieditfield(app.TriangularWaveSetTab, 'text');
            app.TriangularWaveSignalAmplitudeEdit.Value = '1';
            app.sEditFieldLabel = uilabel(app.SignalDemoUIFigure);
            app.sEditFieldLabel.Text = '动画速度(s)：';
            app.AnimationSpeedEdit = uieditfield(app.SignalDemoUIFigure, 'numeric');
            app.AnimationSpeedEdit.Value = 0.01;
            % Show the figure after all components are created
            app.SignalDemoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainfrm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SignalDemoUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SignalDemoUIFigure)
        end
    end
end